classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ProcessedImagePanel   matlab.ui.container.Panel
        HistButton_2          matlab.ui.control.Button
        UIAxes2               matlab.ui.control.UIAxes
        ProcessedImage        matlab.ui.control.UIAxes
        OriginalImagePanel    matlab.ui.container.Panel
        HistButton            matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
        OriginalImage         matlab.ui.control.UIAxes
        OptionsPanel          matlab.ui.container.Panel
        KernelEditField       matlab.ui.control.EditField
        KernelEditFieldLabel  matlab.ui.control.Label
        ProcessButton         matlab.ui.control.Button
        ChooseImageButton     matlab.ui.control.Button
        ModeDropDown          matlab.ui.control.DropDown
        ModeDropDownLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global img;
            global ref;
            global newImg;
            img = -1;
            ref = -1;
            newImg = -1;
        end

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
           global img;
           [filename, pathname] = uigetfile('*.*', 'pick an image');
           filename = strcat(pathname, filename);
           img = imread(filename);
           %get hist
           hist = CustomHist;
           [freq, uniqueVals] = hist.getHistData(img);
           bar(app.OriginalHistogram, uniqueVals(1:end-1), freq);
           imshow(img, 'Parent', app.OriginalImage);
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;
            
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            global newImg;
            global img;
            global ref;
            value = app.ModeDropDown.Value;
        end

        % Callback function: not associated with a component
        function ImageReferenceButtonPushed(app, event)
           global ref;
           [filename, pathname] = uigetfile('*.*', 'pick an image');
           filename = strcat(pathname, filename);
           ref = imread(filename);
           app.FilenameLabel.Text = strcat("Filename : ", filename);
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.ModeDropDownLabel = uilabel(app.OptionsPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.OptionsPanel);
            app.ModeDropDown.Items = {'placeholder'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'placeholder';
            app.ChooseImageButton = uibutton(app.OptionsPanel, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.ProcessButton = uibutton(app.OptionsPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.KernelEditFieldLabel = uilabel(app.OptionsPanel);
            app.KernelEditFieldLabel.Text = 'Kernel';
            app.KernelEditField = uieditfield(app.OptionsPanel, 'text');
            app.OriginalImagePanel = uipanel(app.UIFigure);
            app.OriginalImagePanel.Title = 'Original Image';
            app.OriginalImage = uiaxes(app.OriginalImagePanel);
            title(app.OriginalImage, 'Image')
            xlabel(app.OriginalImage, 'X')
            ylabel(app.OriginalImage, 'Y')
            zlabel(app.OriginalImage, 'Z')
            app.UIAxes = uiaxes(app.OriginalImagePanel);
            title(app.UIAxes, 'Fourier')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.HistButton = uibutton(app.OriginalImagePanel, 'push');
            app.HistButton.Text = 'Hist';
            app.ProcessedImagePanel = uipanel(app.UIFigure);
            app.ProcessedImagePanel.Title = 'Processed Image';
            app.ProcessedImage = uiaxes(app.ProcessedImagePanel);
            title(app.ProcessedImage, 'Image')
            xlabel(app.ProcessedImage, 'X')
            ylabel(app.ProcessedImage, 'Y')
            zlabel(app.ProcessedImage, 'Z')
            app.UIAxes2 = uiaxes(app.ProcessedImagePanel);
            title(app.UIAxes2, 'Fourier')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.HistButton_2 = uibutton(app.ProcessedImagePanel, 'push');
            app.HistButton_2.Text = 'Hist';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end